classdef markov_ligand_enzymes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ButtonGroup                     matlab.ui.container.ButtonGroup
        startButton                     matlab.ui.control.Button
        PlotCheckBox                    matlab.ui.control.CheckBox
        Edgehits_minEditFieldLabel      matlab.ui.control.Label
        Edgehits_minEditField           matlab.ui.control.NumericEditField
        Foundsteps_minEditFieldLabel    matlab.ui.control.Label
        Foundsteps_minEditField         matlab.ui.control.NumericEditField
        Hitsteps_minEditFieldLabel      matlab.ui.control.Label
        Hitsteps_minEditField           matlab.ui.control.NumericEditField
        Successes_maxEditFieldLabel     matlab.ui.control.Label
        Successes_maxEditField          matlab.ui.control.NumericEditField
        enzymesEditFieldLabel           matlab.ui.control.Label
        enzymesEditField                matlab.ui.control.NumericEditField
        enzymesEditField_2Label         matlab.ui.control.Label
        enzymesEditField_2              matlab.ui.control.NumericEditField
        enzymesEditField_3Label         matlab.ui.control.Label
        enzymesEditField_3              matlab.ui.control.NumericEditField
        enzymesEditField_4Label         matlab.ui.control.Label
        enzymesEditField_4              matlab.ui.control.NumericEditField
        figuresDropDownLabel            matlab.ui.control.Label
        figuresDropDown                 matlab.ui.control.DropDown
        TamperatureKEditFieldLabel      matlab.ui.control.Label
        TamperatureKEditField           matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        TimesEditField                  matlab.ui.control.NumericEditField
        DiffusiondistancesnmsEditFieldLabel  matlab.ui.control.Label
        DiffusiondistancesnmsEditField  matlab.ui.control.NumericEditField
        Viscositym2sEditFieldLabel      matlab.ui.control.Label
        Viscositym2sEditField           matlab.ui.control.NumericEditField
        EnzymesEditFieldLabel           matlab.ui.control.Label
        EnzymesEditField                matlab.ui.control.NumericEditField
        X0EditFieldLabel                matlab.ui.control.Label
        X0EditField                     matlab.ui.control.EditField
        SimulationtimesEditFieldLabel   matlab.ui.control.Label
        SimulationtimesEditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        x = [26,26,26];
        Steps;
        steps = [];%sucessful steps
        Running;
        T = 310.15;
        t = 1;
        x2 = 1;
        N = 1;%enzyme numbers
        NN = 1;%%simulation times
        s1=[];%edge hitting steps
        o;
        N0 = [];
        N1 = [];
        p = [];
        PB;
        figures = 'Random Walkers';
        iters = 1;
        Plot = true;
        h = [];
    end


    methods (Access = private)
        
        function [ss,h] = diffusions( app, N, steps, dx, o)
            if steps < 25 
                b =2*steps+1;
            else 
                b = 50;
            end
            P = randi(b,N,3);
            app.PB = [P-[1,0,0];P+[1,0,0];P-[0,1,0];P+[0,1,0];P-[0,0,1];P+[0,0,1]]
            [ss,h] = markov3d2(app, app.PB, steps, dx, o)
        end
    
        function [ss, h] = markov3d2(app, PB, steps, dx, o)
            x = app.x;
            direction = [1,0,0;-1,0,0;0,1,0;0,-1,0;0,0,1;0,0,-1];
            for i = 1:steps
                x = x+dx*direction(randi(6,1,1),:)
%                 if  app.Running == false
%                     app.startButton.Text = 'start';
%                     break;
%                 end
                if o == true
                    plot3(app.UIAxes, x(1),x(2),x(3),'o',PB(1,:),PB(2,:),PB(3,:),'x');
                    drawnow
                end
                if ((x(1) ==1) || (x(1) == 51) || (x(2) ==1) || (x(2) ==51) || (x(3) == 1) || (x(3) == 51))
                    h = 0
%                    app.h = h;
                    app.Running = false;  
                    app.startButton.Text = 'plot';
                    ss = i
%                    app.steps = i;
                    break;
                else
                    h = 2
                    app.Running = false;
                    app.startButton.Text = 'plot';
                    ss = i
                end
                for j = 1:length(PB)
                    if x==PB(j,:)
                        h = 1
                        app.Running = false;
                        app.startButton.Text = 'plot';
                        ss = i
                        break;
                    end
                end
                if h == 1
                    ss = i
                    app.Running = false;
                    app.startButton.Text = 'plot';
                    break;
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Running = false;
            app.startButton.Text = 'start';
            R = 100*10^(-12);
            A = 2.414*10^(-5);
            B = 247.8;
            C = 140;
            eta = A*10^(B/(app.T-C));
            kb = 1.38*10^(-23);
            D = kb*app.T/(6*pi*eta*R);
            dt = app.x2*10^(-18)/(4*D);
            app.Steps = round(app.t/dt)+1;
            app.Foundsteps_minEditField.Value = app.Steps;
            plot3(app.UIAxes, app.x(1),app.x(2),app.x(3),'o');
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            switch app.figures
                case 'Random Walkers'
                    if app.Running == true
                        app.Running = false;
                        app.startButton.Text = 'start';
                    end
                    for n = 1:app.N
                        app.EnzymesEditField.Value = n;
                        app.Running = true;
                        app.startButton.Text = 'stop';
                        ss = zeros(app.NN,app.N);
                        h = zeros(app.NN,app.N);
                        for  j = 1: app.NN
                            app.Running = true;
                            app.startButton.Text = 'stop';
                            app.iters = j
                            app.SimulationtimesEditField.Value = app.iters;
                            [ss(j,n), h(j,n)] = diffusions(app,app.N,app.Steps,app.x2,app.Plot);
                            if h(j,n) == 1
                                app.steps(j,n) = ss(j,n);
                            elseif h(j,n) == 0
                                app.s1(j,n) = ss(j,n);
                            end
                        end
%                        app.p(n) = sum(h==1,1)/app.NN;
%                        app.N0(n) = sum(h(:n)==0,1);
%                        app.N1(n) = sum(h==1,1);
%                        app.s(n) = s/app.N1(n);
%                        app.Foundsteps_minEditField.Value =app.s(n)
%                        app.Successes_maxEditField.Value = app.N1(n)
%                        app.Edgehits_minEditField.Value = app.N0(n)
                    end
                    1:app.N
                    
                    
                    app.p = sum(h==1,1)/app.NN % the nth term means for n enzymes, the possibility for one to be found in total NN times  
                    app.N0 = sum(h==0,1) %the nth term means for n enzymes, the ligand hit the edge N0 times 
                    app.N1 = sum(h==1,1) % the nth term means for n enzymes, one is found N1 times 
                    app.steps = sum(app.steps,1)./(app.N1++0.0000001);% the nth term means for n enzymes, average successful steps
                    app.s1 = sum(app.s1,1)./(app.N0+0.0000001);% the nth term means for n enzymes, average edge hitting steps
                    [app.Successes_maxEditField.Value,app.enzymesEditField.Value] = max(app.N1);
                    [app.Edgehits_minEditField.Value,app.enzymesEditField_2.Value] = min(app.N0);
                    [app.Foundsteps_minEditField.Value,app.enzymesEditField_3.Value] = min(app.steps);
                    [app.Hitsteps_minEditField.Value,app.enzymesEditField_4.Value] = min(app.s1);
                case 'edge hits'
                    pause;
                     1:app.N
%                    app.p = sum(h==1,1)/app.NN % the nth term means for n enzymes, the possibility for one to be found in total NN times  
%                    app.N0 = sum(h==0,1) %the nth term means for n enzymes, the ligand hit the edge N0 times 
%                    app.N1 = sum(h==1,1) % the nth term means for n enzymes, one is found N1 times 
%                    app.steps = sum(ss,1)/app.N1;% the nth term means for n enzymes, average successful steps
                    app.s1 = sum(app.s1,1)./(app.N0+0.0000001);% the nth term means for n enzymes, average edge hitting steps
                    app.Running = false; 
                    plot(app.UIAxes,1:app.N,app.N0);
                    [app.Successes_maxEditField.Value,app.enzymesEditField.Value] = max(app.N1);
                    [app.Edgehits_minEditField.Value,app.enzymesEditField_2.Value] = min(app.N0);
                    [app.Foundsteps_minEditField.Value,app.enzymesEditField_3.Value] = min(app.steps);
                    [app.Hitsteps_minEditField.Value,app.enzymesEditField_4.Value] = min(app.s1);
                case 'successful probablities'
                    pause;
%                    app.p = sum(h==1,1)/app.NN % the nth term means for n enzymes, the possibility for one to be found in total NN times  
%                    app.N0 = sum(h==0,1) %the nth term means for n enzymes, the ligand hit the edge N0 times 
%                    app.N1 = sum(h==1,1) % the nth term means for n enzymes, one is found N1 times 
%                    app.steps = sum(ss,1)/app.N1;% the nth term means for n enzymes, average successful steps
                    app.s1 = sum(app.s1,1)./(app.N0+0.0000001);% the nth term means for n enzymes, average edge hitting steps 
                    app.Running = false
                    1:app.N
                    app.p
                    plot(app.UIAxes,1:app.N,app.p);                    
                    [app.Successes_maxEditField.Value,app.enzymesEditField.Value] = max(app.N1);
                    [app.Edgehits_minEditField.Value,app.enzymesEditField_2.Value] = min(app.N0);
                    [app.Foundsteps_minEditField.Value,app.enzymesEditField_3.Value] = min(app.steps);
                    [app.Hitsteps_minEditField.Value,app.enzymesEditField_4.Value] = min(app.s1);
                case 'average steps'
                    pause;
%                    app.p = sum(h==1,1)/app.NN % the nth term means for n enzymes, the possibility for one to be found in total NN times  
%                    app.N0 = sum(h==0,1) %the nth term means for n enzymes, the ligand hit the edge N0 times 
%                    app.N1 = sum(h==1,1) % the nth term means for n enzymes, one is found N1 times 
%                    app.steps = sum(ss,1)/app.N1;% the nth term means for n enzymes, average successful steps
                    app.s1 = sum(app.s1,1)./(app.N0+0.00001);% the nth term means for n enzymes, average edge hitting steps
 
                    app.Running = false
                    app.steps
                    %plot(app.UIAxes,1:app.N,app.steps);
                    plot(app.UIAxes,1:app.N,app.steps);
                    [app.Successes_maxEditField.Value,app.enzymesEditField.Value] = max(app.N1);
                    [app.Edgehits_minEditField.Value,app.enzymesEditField_2.Value] = min(app.N0);
                    [app.Foundsteps_minEditField.Value,app.enzymesEditField_3.Value] = min(app.steps);
                    [app.Hitsteps_minEditField.Value,app.enzymesEditField_4.Value] = min(app.s1);
                case 'probability gradients'
                    pause;
%                    app.p = sum(h==1,1)/app.NN % the nth term means for n enzymes, the possibility for one to be found in total NN times  
%                    app.N0 = sum(h==0,1) %the nth term means for n enzymes, the ligand hit the edge N0 times 
%                    app.N1 = sum(h==1,1) % the nth term means for n enzymes, one is found N1 times 
%                    app.steps = sum(ss,1)/app.N1;% the nth term means for n enzymes, average successful steps
                    app.s1 = sum(app.s1,1)./(app.N0+0.00001);;% the nth term means for n enzymes, average edge hitting steps
 
                    app.Running = false
                    app.N1
                    [X,Y]=meshgrid(app.steps,app.N1);
                    p = X/app.NN
                    pcolor(app.UIAxes,X,Y,p);
                    [app.Successes_maxEditField.Value,app.enzymesEditField.Value] = max(app.N1);
                    [app.Edgehits_minEditField.Value,app.enzymesEditField_2.Value] = min(app.N0);
                    [app.Foundsteps_minEditField.Value,app.enzymesEditField_3.Value] = min(app.steps);
                    [app.Hitsteps_minEditField.Value,app.enzymesEditField_4.Value] = min(app.s1);
            end
        end

        % Value changed function: PlotCheckBox
        function PlotCheckBoxValueChanged(app, event)
            app.Plot = app.PlotCheckBox.Value;
        end

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            app.x = str2num(app.X0EditField.Value);
            
        end

        % Value changed function: TamperatureKEditField
        function TamperatureKEditFieldValueChanged(app, event)
            app.T = app.TamperatureKEditField.Value;
            if app.T < 0
                uialert(app.UIFigure,me.message,'Temperature in Kalvin should be positive.')
            end
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            app.t = app.TimesEditField.Value;
            
        end

        % Value changed function: DiffusiondistancesnmsEditField
        function DiffusiondistancesnmsEditFieldValueChanged(app, event)
            app.x2 = app.DiffusiondistancesnmsEditField.Value;
            
        end

        % Value changed function: EnzymesEditField
        function EnzymesEditFieldValueChanged(app, event)
            app.N = app.EnzymesEditField.Value;
            
        end

        % Value changed function: Successes_maxEditField
        function Successes_maxEditFieldValueChanged(app, event)
            app.s = app.Successes_maxEditField.Value; 
        end

        % Value changed function: Edgehits_minEditField
        function Edgehits_minEditFieldValueChanged(app, event)
            app.N0 = app.Edgehits_minEditField.Value;          
        end

        % Value changed function: Foundsteps_minEditField
        function Foundsteps_minEditFieldValueChanged(app, event)
            app.Edgehits_minEditField.Value = app.Steps;
        end

        % Value changed function: figuresDropDown
        function figuresDropDownValueChanged(app, event)
            app.figures = app.figuresDropDown.Value;            
        end

        % Value changed function: SimulationtimesEditField
        function SimulationtimesEditFieldValueChanged(app, event)
            app.NN = app.SimulationtimesEditField.Value; 
            switch app.figures
                case 'Random Walkers'
                    if app.Running == true
                        app.Running = false;
                        app.startButton.Text = 'start';
                    end
            end
        end

        % Value changed function: enzymesEditField
        function enzymesEditFieldValueChanged(app, event)
            value = app.enzymesEditField.Value;
            
        end

        % Value changed function: enzymesEditField_2
        function enzymesEditField_2ValueChanged(app, event)
            value = app.enzymesEditField_2.Value;
            
        end

        % Value changed function: enzymesEditField_3
        function enzymesEditField_3ValueChanged(app, event)
            value = app.enzymesEditField_3.Value;
            
        end

        % Value changed function: enzymesEditField_4
        function enzymesEditField_4ValueChanged(app, event)
            value = app.enzymesEditField_4.Value;
            
        end

        % Value changed function: Hitsteps_minEditField
        function Hitsteps_minEditFieldValueChanged(app, event)
            value = app.Hitsteps_minEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.startButton = uibutton(app.ButtonGroup, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.PlotCheckBox = uicheckbox(app.ButtonGroup);
            app.PlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotCheckBoxValueChanged, true);
            app.PlotCheckBox.Text = 'Plot';
            app.PlotCheckBox.Value = true;
            app.Edgehits_minEditFieldLabel = uilabel(app.ButtonGroup);
            app.Edgehits_minEditFieldLabel.Text = 'Edge hits_min';
            app.Edgehits_minEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Edgehits_minEditField.ValueChangedFcn = createCallbackFcn(app, @Edgehits_minEditFieldValueChanged, true);
            app.Edgehits_minEditField.Editable = 'off';
            app.Foundsteps_minEditFieldLabel = uilabel(app.ButtonGroup);
            app.Foundsteps_minEditFieldLabel.Text = 'Found steps_min';
            app.Foundsteps_minEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Foundsteps_minEditField.ValueChangedFcn = createCallbackFcn(app, @Foundsteps_minEditFieldValueChanged, true);
            app.Foundsteps_minEditField.Editable = 'off';
            app.Hitsteps_minEditFieldLabel = uilabel(app.ButtonGroup);
            app.Hitsteps_minEditFieldLabel.Text = 'Hit steps_min ';
            app.Hitsteps_minEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Hitsteps_minEditField.ValueChangedFcn = createCallbackFcn(app, @Hitsteps_minEditFieldValueChanged, true);
            app.Hitsteps_minEditField.Editable = 'off';
            app.Successes_maxEditFieldLabel = uilabel(app.ButtonGroup);
            app.Successes_maxEditFieldLabel.Text = 'Successes_max';
            app.Successes_maxEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.Successes_maxEditField.ValueChangedFcn = createCallbackFcn(app, @Successes_maxEditFieldValueChanged, true);
            app.Successes_maxEditField.Editable = 'off';
            app.enzymesEditFieldLabel = uilabel(app.ButtonGroup);
            app.enzymesEditFieldLabel.Text = 'enzymes';
            app.enzymesEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.enzymesEditField.ValueChangedFcn = createCallbackFcn(app, @enzymesEditFieldValueChanged, true);
            app.enzymesEditField.Editable = 'off';
            app.enzymesEditField_2Label = uilabel(app.ButtonGroup);
            app.enzymesEditField_2Label.Text = 'enzymes';
            app.enzymesEditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.enzymesEditField_2.ValueChangedFcn = createCallbackFcn(app, @enzymesEditField_2ValueChanged, true);
            app.enzymesEditField_2.Editable = 'off';
            app.enzymesEditField_3Label = uilabel(app.ButtonGroup);
            app.enzymesEditField_3Label.Text = 'enzymes';
            app.enzymesEditField_3 = uieditfield(app.ButtonGroup, 'numeric');
            app.enzymesEditField_3.ValueChangedFcn = createCallbackFcn(app, @enzymesEditField_3ValueChanged, true);
            app.enzymesEditField_3.Editable = 'off';
            app.enzymesEditField_4Label = uilabel(app.ButtonGroup);
            app.enzymesEditField_4Label.Text = 'enzymes';
            app.enzymesEditField_4 = uieditfield(app.ButtonGroup, 'numeric');
            app.enzymesEditField_4.ValueChangedFcn = createCallbackFcn(app, @enzymesEditField_4ValueChanged, true);
            app.enzymesEditField_4.Editable = 'off';
            app.figuresDropDownLabel = uilabel(app.UIFigure);
            app.figuresDropDownLabel.Text = 'figures';
            app.figuresDropDown = uidropdown(app.UIFigure);
            app.figuresDropDown.Items = {'Random Walkers', 'edge hits', 'successful probablities', 'average steps', 'probability gradients'};
            app.figuresDropDown.ValueChangedFcn = createCallbackFcn(app, @figuresDropDownValueChanged, true);
            app.figuresDropDown.Value = 'Random Walkers';
            app.TamperatureKEditFieldLabel = uilabel(app.UIFigure);
            app.TamperatureKEditFieldLabel.Text = 'Tamperature(K)';
            app.TamperatureKEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamperatureKEditField.ValueChangedFcn = createCallbackFcn(app, @TamperatureKEditFieldValueChanged, true);
            app.TamperatureKEditField.Value = 310.15;
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time(s)';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Value = 1;
            app.DiffusiondistancesnmsEditFieldLabel = uilabel(app.UIFigure);
            app.DiffusiondistancesnmsEditFieldLabel.Text = 'Diffusion distances(nm^s)';
            app.DiffusiondistancesnmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiffusiondistancesnmsEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusiondistancesnmsEditFieldValueChanged, true);
            app.DiffusiondistancesnmsEditField.Value = 1;
            app.Viscositym2sEditFieldLabel = uilabel(app.UIFigure);
            app.Viscositym2sEditFieldLabel.Text = 'Viscosity(m^2/s)';
            app.Viscositym2sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Viscositym2sEditField.Value = 0.00069;
            app.EnzymesEditFieldLabel = uilabel(app.UIFigure);
            app.EnzymesEditFieldLabel.Text = 'Enzymes';
            app.EnzymesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnzymesEditField.ValueChangedFcn = createCallbackFcn(app, @EnzymesEditFieldValueChanged, true);
            app.EnzymesEditField.Value = 1;
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'text');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            app.X0EditField.Value = '[26,26,26]';
            app.SimulationtimesEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation times';
            app.SimulationtimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimesEditFieldValueChanged, true);
            app.SimulationtimesEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = markov_ligand_enzymes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end